classdef CustomRegionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UITable   matlab.ui.control.Table
    end

    
    properties (Access = private)
        MTGUI;
        RegionTbl;
        RegionIdx;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.MTGUI = varargin{1};
            app.RegionTbl = app.MTGUI.RWA.RegionTable;
            app.RegionIdx = varargin{2};
            app.UITable.Data = app.RegionTbl(:,{'Patient','Chan','RegionLabel','ChanLabel'});
            switch app.RegionIdx
                case 1
                    if ~isempty(app.MTGUI.CustomRegion1)
                        sel = nan(size(app.MTGUI.CustomRegion1,1),1);
                        for k=1:size(app.MTGUI.CustomRegion1,1)
                            idx = find(all(app.RegionTbl.Patient==app.MTGUI.CustomRegion1(k,1) & app.RegionTbl.Chan==app.MTGUI.CustomRegion1(k,2),2));
                            if ~isempty(idx)
                                sel(k) = idx;
                            end
                        end
                        app.UITable.Selection = sel(~isnan(sel))';
                    end
                case 2
                    if ~isempty(app.MTGUI.CustomRegion2)
                        sel = nan(size(app.MTGUI.CustomRegion2,1),1);
                        for k=1:size(app.MTGUI.CustomRegion2,1)
                            idx = find(all(app.RegionTbl.Patient==app.MTGUI.CustomRegion2(k,1) & app.RegionTbl.Chan==app.MTGUI.CustomRegion2(k,2),2));
                            if ~isempty(idx)
                                sel(k) = idx;
                            end
                        end
                        app.UITable.Selection = sel(~isnan(sel))';
                    end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % row_idx = app.UITable.Selection;
            % customregion = [app.RegionTbl.Patient(row_idx),app.RegionTbl.Chan(row_idx)];
            % switch app.RegionIdx
            %     case 1
            %         app.MTGUI.CustomRegion1 = customregion;
            %     case 2
            %         app.MTGUI.CustomRegion2 = customregion;
            % end
            delete(app)
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            row_idx = app.UITable.Selection;
            customregion = [app.RegionTbl.Patient(row_idx),app.RegionTbl.Chan(row_idx)];
            switch app.RegionIdx
                case 1
                    app.MTGUI.CustomRegion1 = customregion;
                case 2
                    app.MTGUI.CustomRegion2 = customregion;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Patient'; 'Channel'; 'Region'; 'Region Description'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Tooltip = {'Select the rows to be included in the specgram analysis/plot'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CustomRegionGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end