classdef ExportEpochedDataGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        DescriptionTypeDD  matlab.ui.control.DropDown
        TransTypeLabel_11  matlab.ui.control.Label
        EventTypeDD        matlab.ui.control.DropDown
        TransTypeLabel_10  matlab.ui.control.Label
        DescriptionCkb     matlab.ui.control.CheckBox
        EventCkb           matlab.ui.control.CheckBox
        RegionTypeDD       matlab.ui.control.DropDown
        TransTypeLabel_9   matlab.ui.control.Label
        RegionCkb          matlab.ui.control.CheckBox
        WalkCkb            matlab.ui.control.CheckBox
        PatientCkb         matlab.ui.control.CheckBox
        SelDirEdit         matlab.ui.control.EditField
        SaveDirLabel       matlab.ui.control.Label
        ExportButton       matlab.ui.control.Button
        PatientTypeDD      matlab.ui.control.DropDown
        TransTypeLabel_8   matlab.ui.control.Label
        WalkTypeDD         matlab.ui.control.DropDown
        TransTypeLabel_5   matlab.ui.control.Label
    end

    
    properties (Access = public)
        RWdat % realworld analysis object
    end
    
    methods (Access = private)
        
        function LoadNewData(app)
            app.RWdat.pID = app.PatientTypeDD.ValueIndex;
            app.RWdat.wID = app.WalkTypeDD.ValueIndex;
            dat = load("RWNApp_RW"+app.RWdat.pID+"_Walk"+app.RWdat.wID+".mat");
            app.RWdat.allVars = dat;
            app.RWdat.evTable = dat.evnts_tbl;
            
            UpdateTransitionDescription(app);
        end
        
        function UpdateTransitionDescription(app)
            evCounts = groupsummary(app.RWdat.evTable,"Event");
            keepEvents = true(length(evCounts.Event),1);
            if(app.RemoveBeginTransitionsCheckBox.Value)
                keepEvents = ~contains(evCounts.Event,"Beg","IgnoreCase",true) & keepEvents;
            end
            if(app.RemoveEndTransitionsCheckBox.Value)
                keepEvents = ~contains(evCounts.Event,"End","IgnoreCase",true) & keepEvents;
            end
            evUnique = evCounts.Event(evCounts.GroupCount>=app.ThresholdEditField.Value & keepEvents);
            
            app.TransitionsLB.Items = evUnique;
            app.RWdat.evTable.Include = ismember(app.RWdat.evTable.Event,evUnique);

            [~, descriptionUnique] = TextProcess.ExtractDescriptions(app.RWdat.evTable.Description(app.RWdat.evTable.Include), '\[([^\]]+)\]');

            app.DescriptionLB.Items = descriptionUnique;

            app.SelectAllTransitionsCkb.Value = true;
            app.SelectAllDescriptionCkb.Value = true;
            app.TransitionsLB.Value = app.TransitionsLB.Items;
            app.DescriptionLB.Value = app.DescriptionLB.Items;
        end
        
        function varNameList = FindDataVariables(app,varNameCode)
            allVarStruct = app.RWdat.allVars;
            fieldNames = fieldnames(allVarStruct);
            fieldNames = fieldNames(contains(fieldNames,'d_'));
            varNameList = [];
            % Loop through all vars to find those starting with "d_"
            for i = 1:numel(fieldNames)
                fieldName = fieldNames{i};
                if startsWith(fieldName, varNameCode) % Check if the field name starts with "d_"
                    newName = extractAfter(fieldName, varNameCode); % Remove "d_"
                    value = allVarStruct.(fieldName); % Access the field's value
                    % Check if the variable is empty
                    % Currently only tables and arrays are counted
                    if (~isempty(value))
                        if(istable(value))
                            for inerIdx=1:length(value.Properties.VariableNames)
                                varNameList = cat(1,varNameList,string(newName)+"."+string(value.Properties.VariableNames{inerIdx}));    
                            end
                        elseif(size(value,2)==1) 
                            varNameList = cat(1,varNameList,string(newName));
                        end
                        
                    end
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % Automatically read the patient list and walk from the file
            % names based on ..RWX_WalkY.mat

% pID = 1;
% patient(pID).exp = 'Original';
% patient(pID).name  = 'amyg001';
% patient(pID).dataPath = 'day1_lfp_095.mat';
% patient(pID).samprate = 250; 
% patient(pID).region.names = [];
% patient(pID).region.channelIdx = [];
% patient(pID).phase.name = [];
% patient(pID).phase.data = [];


            files = dir('*.mat');
            fileNames = {files.name};
            tokens = TextProcess.ExtractPatientWalkFromFileNames(fileNames, 'RW(\d+)_Walk(\d+)\.mat');
            if isempty(tokens) % Check if patient files were found
                disp('No patient file has been found');
                folderPath = uigetdir(pwd, 'Select the folder that contains individual data');
                if folderPath ~= 0 % If the user selects a folder
                    files = dir(fullfile(folderPath,'*.mat'));
                    fileNames = {files.name};
                    tokens = TextProcess.ExtractPatientWalkFromFileNames(fileNames, 'RW(\d+)_Walk(\d+)\.mat');
                    addpath(genpath(folderPath));
                else
                    error('Patient files not found');
                end
            end
            app.RWdat.patientNumbers = tokens(:,1);
            app.RWdat.walkNumbers = tokens(:,2);

            app.PatientTypeDD.Items = cat(1,"All",string(unique(app.RWdat.patientNumbers)));
            app.PatientTypeDD.ValueIndex = 1;
            app.WalkTypeDD.Items = cat(1,"All",string(unique(app.RWdat.walkNumbers)));

            LoadNewData(app);           
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            
            %%%%%%%%%%%%%%%% ---------------------------------> Change
            %%%%%%%%%%%%%%%% Transision.items to Values
            evUnique = app.TransitionsLB.Value;
            uniqueDescrip = app.DescriptionLB.Value;
            orderStr = app.SortDropDown.Value;

            app.RWdat.evTable.Include = ismember(app.RWdat.evTable.Event,evUnique);
            evTable = app.RWdat.evTable(app.RWdat.evTable.Include,:);
            [evTable.Description, descriptionUnique] = TextProcess.ExtractDescriptions(evTable.Description, '\[([^\]]+)\]');
            
            %------ process descriptions
            for desIdx = 1:length(uniqueDescrip)
                evTable.(uniqueDescrip{desIdx}) = contains(evTable.Description,uniqueDescrip{desIdx});
            end
            % for evIdx = 1:length(evUnique)
            %     tTemp = Abnormal
            % end


            %=============== end process descriptions

            tStart = app.RWdat.evTable.NTP(1);
            % tEnd = app.RWdat.evTable.NTP(end)/60*1000;
            timeVals = (evTable.NTP-tStart)/60*1000;
            tArray = (0:timeVals(end))';
            tIdx = knnsearch(tArray,timeVals);
            
            % Find Event Time Series
            evTimeSeries = zeros(length(tArray),length(evUnique));
            evTimeSeries2 = zeros(length(tArray),length(evUnique));
            for evIdx = 1:length(evUnique)
                evName = string(evUnique{evIdx});
                evTimeSeries(tIdx,evIdx) = strcmp(evTable.Event,evName);
                evTimeSeries2(:,evIdx) = conv(evTimeSeries(:,evIdx),hamming(20),'same');
                evTimeSeries2(:,evIdx) = evTimeSeries2(:,evIdx)/max(evTimeSeries2(:,evIdx));
            end

            if(~app.NotPairwiseCorrCheckBox.Value)
                % -------------- Compute pairwise Correlations
                corrMat = corr(evTimeSeries2);
                corrMat(eye(size(corrMat))==1)=NaN;
                sumCorr = sum(abs(corrMat),2,"omitmissing");
                if(strcmp(orderStr,'none'))
                    order = 1:length(sumCorr);
                else
                    [~, order] = sort(sumCorr, orderStr);
                end
            else
                % -------------- Correlation with the desire variable
                % Extract the reference variable
                refVarName = string(app.ReferenceVariableDropDown.Value);
                eval("refVarVal = app.RWdat.allVars."+"d_"+refVarName+";")
                if(contains(refVarName,"."))
                    eval("refVarNTP = app.RWdat.allVars."+"ntp_"+extractBefore(refVarName,".")+";")
                else
                    eval("refVarNTP = app.RWdat.allVars."+"ntp_"+refVarName+";")
                end
                refVarNTP = (refVarNTP - tStart)/60;

                % Find the corresponding timepoints and values
                tIdx = knnsearch(refVarNTP,tArray);
                refVarVal = fillmissing(refVarVal,'linear');
                refVarVal = zscore(refVarVal);
                % Replace outliers
                refVarVal(refVarVal>3) = 3;
                refVarVal(refVarVal<-3) = -3;
                refVarVal = refVarVal(tIdx);

                % -------------- Compute Correlations
                corrMat = corr(evTimeSeries,refVarVal);
                if(strcmp(orderStr,'none'))
                    order = 1:length(corrMat);
                else
                    [~, order] = sort(corrMat, orderStr);
                end
            end

            
            figure;
            ax = subplot(2,1,1);
            % evTimeSeries = evTimeSeries(:,order);
            yVals = 1:length(evUnique);
            xLabel = "time(min)";
            xVals = tArray/1000;
            % ax = axes(figure);
            Plot.RasterPlot(ax, evUnique(order), evTable, yVals, xVals, xLabel, evTimeSeries2);
            % Plot.RasterPlot(ax, evUnique(order), evTable, yVals, xVals, xLabel, evTimeSeries);
            if(app.NotPairwiseCorrCheckBox.Value && app.PlotReferenceVariableCheckBox.Value)
                plot(ax, tArray, refVarVal-1,'k')
                ylim([min(refVarVal-1),length(evUnique)+1])
            end

            ax2 = subplot(2,2,3);
            treeMat = linkage(evTimeSeries',"average",'correlation');
            % leafOrder = optimalleaforder(tree,order);
            % tree = linkage(X,"average");
            dendrogram(ax2, treeMat,'Labels', evUnique, Reorder = order,Orientation="right",ColorThreshold="default");


            ax3 = subplot(2,2,4);
            if(size(corrMat,2)==1)
                imagesc(ax3,corrMat); % Display the matrix as an image
                colorbar; % Add a colorbar for reference
                clim([-1, 1]); % Set color limits for correlation values (-1 to 1)
                colormap(ax3,winter); % Set the colormap
                
                % Customize the axes
                xticks(1); % Set x-axis ticks
                yticks(1:length(evUnique)); % Set y-axis ticks
                xticklabels(refVarName); % Label x-axis ticks with variable names
                yticklabels(evUnique); % Label y-axis ticks with variable names
                title('Correlation Matrix');
            else
                corrMat(eye(size(corrMat))==1)=NaN;
                imagesc(ax3,corrMat); % Display the matrix as an image
                colorbar; % Add a colorbar for reference
                clim([-1, 1]); % Set color limits for correlation values (-1 to 1)
                colormap(ax3,winter); % Set the colormap
                
                % Customize the axes
                xticks(1:length(evUnique)); % Set x-axis ticks
                yticks(1:length(evUnique)); % Set y-axis ticks
                xticklabels(evUnique); % Label x-axis ticks with variable names
                yticklabels(evUnique); % Label y-axis ticks with variable names
                title('Correlation Matrix');
                axis equal
            end

        end

        % Value changed function: PatientTypeDD
        function PatientTypeDDValueChanged(app, event)
            app.WalkTypeDD.Items = string(unique(app.RWdat.walkNumbers(app.RWdat.patientNumbers == app.PatientTypeDD.ValueIndex)));
        end

        % Value changed function: WalkTypeDD
        function WalkTypeDDValueChanged(app, event)
            app.WalkTypeDD.Items = string(unique(app.RWdat.walkNumbers(app.RWdat.patientNumbers == app.PatientTypeDD.ValueIndex)));
            LoadNewData(app);
        end

        % Callback function: not associated with a component
        function ThresholdEditFieldValueChanged(app, event)
            UpdateTransitionDescription(app);
        end

        % Callback function: not associated with a component
        function SelectAllTransitionsCkbValueChanged(app, event)
            if(app.SelectAllTransitionsCkb.Value)
                app.TransitionsLB.Value = app.TransitionsLB.Items;
            else
                app.TransitionsLB.Value = app.TransitionsLB.Items(1);
            end
            
        end

        % Callback function: not associated with a component
        function SelectAllDescriptionCkbValueChanged(app, event)
            if(app.SelectAllDescriptionCkb.Value)
                app.DescriptionLB.Value = app.DescriptionLB.Items;
            else
                app.DescriptionLB.Value = app.DescriptionLB.Items(1);
            end
            
        end

        % Callback function: not associated with a component
        function RemoveBeginTransitionsCheckBoxValueChanged(app, event)
            UpdateTransitionDescription(app);            
        end

        % Callback function: not associated with a component
        function RemoveEndTransitionsCheckBoxValueChanged(app, event)
            UpdateTransitionDescription(app);
        end

        % Callback function
        function NotPairwiseCorrCheckBoxValueChanged(app, event)
            if(app.NotPairwiseCorrCheckBox.Value)
                varNameList = FindDataVariables(app,'d_');
                if(~isempty(varNameList))
                    app.ReferenceVariableDropDown.Items = varNameList;
                    app.ReferenceVariableDropDown.ValueIndex = 1;
                    app.ReferenceVariableDropDown.Enable = true;
                    app.PlotReferenceVariableCheckBox.Enable = true;
                    app.PlotReferenceVariableCheckBox.Value = true;
                end
            else
                app.ReferenceVariableDropDown.Items = "pairwise";
                app.ReferenceVariableDropDown.Value = "pairwise";
                app.ReferenceVariableDropDown.Enable = false;
                app.PlotReferenceVariableCheckBox.Enable = false;
                app.PlotReferenceVariableCheckBox.Value = false;
            end
            
        end

        % Callback function: not associated with a component
        function CloseAllButtonPushed(app, event)
            close all
        end

        % Callback function
        function PlotCorrMatButtonPushed(app, event)
            evUnique = app.TransitionsLB.Value;
            Patients = str2num(app.PatientsEditField.Value);
            Walks = str2num(app.WalksEditField.Value);
            Plot.PlotAverageCorrelationMatrix(evUnique,Walks,Patients)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TransTypeLabel_5 = uilabel(app.UIFigure);
            app.TransTypeLabel_5.Text = 'Walk';
            app.WalkTypeDD = uidropdown(app.UIFigure);
            app.WalkTypeDD.Items = {''};
            app.WalkTypeDD.Editable = 'on';
            app.WalkTypeDD.ValueChangedFcn = createCallbackFcn(app, @WalkTypeDDValueChanged, true);
            app.WalkTypeDD.Tooltip = {'A list of walk numbers can be added manually (i.e. 1,2,7)'};
            app.WalkTypeDD.Value = '';
            app.TransTypeLabel_8 = uilabel(app.UIFigure);
            app.TransTypeLabel_8.Text = 'Patient';
            app.PatientTypeDD = uidropdown(app.UIFigure);
            app.PatientTypeDD.Items = {''};
            app.PatientTypeDD.Editable = 'on';
            app.PatientTypeDD.ValueChangedFcn = createCallbackFcn(app, @PatientTypeDDValueChanged, true);
            app.PatientTypeDD.Tooltip = {'A list of patient numbers can be added manually (i.e. 1,3,4)'};
            app.PatientTypeDD.Value = '';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.SaveDirLabel = uilabel(app.UIFigure);
            app.SaveDirLabel.Text = 'Save Dir';
            app.SelDirEdit = uieditfield(app.UIFigure, 'text');
            app.PatientCkb = uicheckbox(app.UIFigure);
            app.PatientCkb.Tooltip = {'Iterate and save'};
            app.PatientCkb.Text = 'All';
            app.WalkCkb = uicheckbox(app.UIFigure);
            app.WalkCkb.Tooltip = {'Iterate and save'};
            app.WalkCkb.Text = 'All';
            app.RegionCkb = uicheckbox(app.UIFigure);
            app.RegionCkb.Tooltip = {'Iterate and save'};
            app.RegionCkb.Text = 'All';
            app.TransTypeLabel_9 = uilabel(app.UIFigure);
            app.TransTypeLabel_9.Text = 'Region';
            app.RegionTypeDD = uidropdown(app.UIFigure);
            app.RegionTypeDD.Items = {''};
            app.RegionTypeDD.Editable = 'on';
            app.RegionTypeDD.Tooltip = {'Region Names'};
            app.RegionTypeDD.Value = '';
            app.EventCkb = uicheckbox(app.UIFigure);
            app.EventCkb.Tooltip = {'Iterate and save'};
            app.EventCkb.Text = 'All';
            app.DescriptionCkb = uicheckbox(app.UIFigure);
            app.DescriptionCkb.Tooltip = {'Iterate and save'};
            app.DescriptionCkb.Text = 'All';
            app.TransTypeLabel_10 = uilabel(app.UIFigure);
            app.TransTypeLabel_10.Text = 'Event';
            app.EventTypeDD = uidropdown(app.UIFigure);
            app.EventTypeDD.Items = {''};
            app.EventTypeDD.Editable = 'on';
            app.EventTypeDD.Tooltip = {'A list of walk numbers can be added manually (i.e. 1,2,7)'};
            app.EventTypeDD.Value = '';
            app.TransTypeLabel_11 = uilabel(app.UIFigure);
            app.TransTypeLabel_11.Text = 'Description';
            app.DescriptionTypeDD = uidropdown(app.UIFigure);
            app.DescriptionTypeDD.Items = {''};
            app.DescriptionTypeDD.Editable = 'on';
            app.DescriptionTypeDD.Tooltip = {'Region Names'};
            app.DescriptionTypeDD.Value = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExportEpochedDataGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end