classdef MultSegFRankingGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        MethodsLB               matlab.ui.control.ListBox
        MethodsLabel            matlab.ui.control.Label
        SelectAllMethodsCkb     matlab.ui.control.CheckBox
        FeatureSelectionLabel   matlab.ui.control.Label
        RunButton               matlab.ui.control.Button
        UniformCkb              matlab.ui.control.CheckBox
        LevelsTextArea          matlab.ui.control.TextArea
        LevelsTextAreaLabel     matlab.ui.control.Label
        PredictorsLB            matlab.ui.control.ListBox
        PredictorsListBoxLabel  matlab.ui.control.Label
        SelectAllPredictorsCkb  matlab.ui.control.CheckBox
        LevelsofOutcomeEF       matlab.ui.control.NumericEditField
        LevelsofOutcomeLabel    matlab.ui.control.Label
        OutcomeDD               matlab.ui.control.DropDown
        OutcomeDropDownLabel    matlab.ui.control.Label
        SaveRawCkb              matlab.ui.control.CheckBox
        WalkCkb                 matlab.ui.control.CheckBox
        PatientCkb              matlab.ui.control.CheckBox
        RegionCkb               matlab.ui.control.CheckBox
        SelDirEdit              matlab.ui.control.EditField
        SaveDirLabel            matlab.ui.control.Label
        SaveFigCkb              matlab.ui.control.CheckBox
        SaveBtn                 matlab.ui.control.Button
        SelectButton            matlab.ui.control.Button
        PatientTypeDD           matlab.ui.control.DropDown
        TransTypeLabel_8        matlab.ui.control.Label
        WalkTypeDD              matlab.ui.control.DropDown
        TransTypeLabel_5        matlab.ui.control.Label
        RegionTypeDD            matlab.ui.control.DropDown
        TransTypeLabel_4        matlab.ui.control.Label
        HistogramAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        RWA % realworld analysis object
        fRankingData; %Filtered version of the data for feature ranking
        CustomRegion1;
        aH; %axis handle 
    end
    
    methods (Access = private)
        
        function UpdateFeatureRankingObjects(app)
            RemoveUniLevelVariables(app);
            if strcmp(app.OutcomeDD.Value,'Select')
                app.LevelsofOutcomeEF.Value = [];
            else
                app.LevelsofOutcomeEF.Value = length(unique(app.fRankingData.df.(app.OutcomeDD.Value)));
            end

            %Exclude the outcome and add the rest of variables to the predictors list
            app.PredictorsLB.Items = setdiff(app.fRankingData.df.Properties.VariableNames,{app.OutcomeDD.Value});
        end
        
        function RemoveUniLevelVariables(app)
            dfTemp = app.fRankingData.df;
            multiLevelVars = false(1,length(dfTemp.Properties.VariableNames)); 
            for varIdx = 1:length(dfTemp.Properties.VariableNames)
                if(length(unique(dfTemp.(varIdx)))>1)
                    multiLevelVars(varIdx) = true;
                end
            end
            app.fRankingData.df = dfTemp(:,multiLevelVars);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin>1
                app.RWA = varargin{1};
                if isempty(app.RWA.MultSeg)
                    uialert(app.UIFigure,'Run getMultSegData first!','warning');
                    return;
                end
            else
                WaitH = uiprogressdlg(app.UIFigure,'Indeterminate','on','Message','Loading...');
                drawnow;
                pause(0.1);
                app.RWA = RWAnalysis2;
                app.RWA.loadMultData;
                close(WaitH);
                if isempty(app.RWA.MultSeg)
                    uialert(app.UIFigure,'RWAnalysis.mat file not found!','warning');
                    return;
                end
            end
            
            %regions
            regionlist = {'AntHipp','LatTemp','Ent+Peri','PostHipp+Para','All Chans','Custom'};
            app.RegionTypeDD.Items = [{''},regionlist]; %app.RegionTypeDD.ValueIndex = 1;

            %patient
            patientlist = {'All Patients','Leave Out 1','Leave Out 2','Leave Out 3','Leave Out 4','Leave Out 5','1','2','3','4','5'};
            app.PatientTypeDD.Items = [{''},patientlist]; app.PatientTypeDD.Value = 'All Patients';

            %walk
            walklist = {'First Walks','Last Walks','Stop Walks','Go Walks','All Walks','1','2','3','4','5','6','7','8'};
            app.WalkTypeDD.Items = [{''},walklist]; app.WalkTypeDD.Value = 'All Walks';


            %Feature Ranking Data
            app.fRankingData.df = app.RWA.MultSeg.MS;
            RemoveUniLevelVariables(app);
            %Update Feature Ranking Objects
            app.OutcomeDD.Items = [{'Select'},app.fRankingData.df.Properties.VariableNames];
            app.PredictorsLB.Items = app.fRankingData.df.Properties.VariableNames;
            
            
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            regiontype = app.RegionTypeDD.Value;
            patienttype = app.PatientTypeDD.Value;
            walktype = app.WalkTypeDD.Value;
            customregion = app.CustomRegion1;
           
            b = [];
            b(1) = isempty(regiontype) && ~app.RegionCkb.Value;
            b(2) = isempty(patienttype) && ~app.PatientCkb.Value;
            b(3) = isempty(walktype) && ~app.WalkCkb.Value;
            b(4) = strcmp(regiontype,'Custom') && isempty(customregion);         

            %Perform some checks
            if any(b)
                uialert(app.UIFigure,'Make sure to select all options!','error');
                return;
            end           

            %Plot
            if app.RegionCkb.Value
                regioncell = app.RegionTypeDD.Items(2:end-1);
            else
                regioncell = {regiontype};
            end
            if app.PatientCkb.Value
                patientcell = app.PatientTypeDD.Items(2:end);
            else
                patientcell = {patienttype};
            end
            if app.WalkCkb.Value
                walkcell = app.WalkTypeDD.Items(2:end);
            else
                walkcell = {walktype};
            end
           
            nregion = length(regioncell);
            npatient = length(patientcell);
            nwalk = length(walkcell);
            ntotal = nregion*npatient*nwalk;

            [ridx,pidx,widx] = ndgrid(1:nregion,1:npatient,1:nwalk);
            idx = [ridx(:),pidx(:),widx(:)];

            if size(idx,1)~=ntotal
                uialert(app.UIFigure,'Iteration size mismatch!','error');
                return;
            end

            iterflag = any([nregion,npatient,nwalk,ntotal]>1);
            if iterflag && (app.SaveFigCkb.Value || app.SaveRawCkb.Value)
                uialert(app.UIFigure,'Uncheck "Raw" or "Fig"','error');
                return;
            end

            app.RWA.filterMultSegData('regiontype',regioncell{:},'walktype',walkcell{:},...
                'patienttype',patientcell{:},'customregion',customregion);
            app.fRankingData.df = app.RWA.MultSeg.MS;

            UpdateFeatureRankingObjects(app);
            app.RunButton.Enable = "on";

        end

        % Button pushed function: SaveBtn
        function SaveBtnFcn(app, event)
            if any(ishandle(app.fH))
                app.SaveBtn.BackgroundColor = 'r';
                if isempty(app.SelDirEdit.Value)
                    app.SelDirEdit.Value = lastDir('Choose the destination folder...');
                end
                if ~isfolder(app.SelDirEdit.Value)
                    mkdir(app.SelDirEdit.Value);
                end
                tStr = app.fH.Name;
                tStr = regexprep(tStr,'\s+','');
                tStr = regexprep(tStr,'\\|/','-');
                tStr = regexprep(tStr,'<','');
                tStr = regexprep(tStr,'::','~');
                tStr = regexprep(tStr,'\|','');
                tStr = regexprep(tStr,'\(',',');
                tStr = regexprep(tStr,'\)','');
                tStr = regexprep(tStr,'=','');
                
                fstr = sprintf('GLMEGUI_%s.png',tStr);
                print(app.fH,fullfile(app.SelDirEdit.Value,fstr),'-dpng','-r300');
                if app.SaveFigCkb.Value
                    savefig(app.fH,fullfile(app.SelDirEdit.Value,regexprep(fstr,'png$','fig')))
                end
                if app.SaveRawCkb.Value
                    MS = app.RWA.MultSeg.MS;
                    save(fullfile(app.SelDirEdit.Value,regexprep(fstr,'png$','mat')),"MS",'-v7.3')
                end
                
                app.SaveBtn.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: RegionTypeDD
        function RegionTypeFcn(app, event)
            if strcmp(app.RegionTypeDD.Value,'Custom')
                CustomRegionGUI(app,1);
            end            
        end

        % Value changed function: OutcomeDD
        function OutcomeChangeFcn(app, event)
            app.HistogramAxes.Visible = false;
            cla(app.HistogramAxes);
            app.LevelsTextArea.Visible = false;
            app.LevelsTextAreaLabel.Visible = false;
            app.LevelsofOutcomeEF.Editable = false;
            app.UniformCkb.Enable = false;

            value = app.OutcomeDD.Value;
            UpdateFeatureRankingObjects(app);
            if(strcmp(value,'Select'))
                return;
            end

            if(isnumeric(app.fRankingData.df.(value)))
                app.HistogramAxes.Visible = true;
                app.fRankingData.outcome = app.fRankingData.df.(value);
                histogram(app.HistogramAxes,app.fRankingData.df.(value));
                app.LevelsofOutcomeEF.Editable = true;
                app.UniformCkb.Enable = true;
            elseif(islogical(app.fRankingData.df.(value)))
                app.HistogramAxes.Visible = true;
                app.fRankingData.outcome = app.fRankingData.df.(value);
                histogram(app.HistogramAxes,categorical(app.fRankingData.df.(value)));
            elseif(iscell(app.fRankingData.df.(value)))
                app.LevelsTextArea.Visible = true;
                app.LevelsTextAreaLabel.Visible  = true;
                app.fRankingData.outcome = app.fRankingData.df.(value);
                app.LevelsTextArea.Value = convertCharsToStrings(unique(app.fRankingData.df.(value)));
            end


            % value = app.OutcomeDD.Value;
            % if(length(unique(app.fRankingData.df.(value)))>=2)
            %     UpdateFeatureRankingObjects(app);
            % else
            %     app.OutcomeDD.ValueIndex = 1;
            %     uialert(app.UIFigure,[value,' has only 1 level! Select another variable!'],'error');
            % end

        end

        % Value changed function: LevelsofOutcomeEF, UniformCkb
        function LevelsofOutcomeEFValueChanged(app, event)
            value = app.LevelsofOutcomeEF.Value;
            if(isempty(value))
                app.fRankingData.outcome = app.fRankingData.df.(app.OutcomeDD.Value);
                histogram(app.HistogramAxes,app.fRankingData.df.(app.OutcomeDD.Value));
                app.LevelsofOutcomeEF.Value = length(unique(app.fRankingData.df.(app.OutcomeDD.Value)));
            elseif(isnumeric(app.fRankingData.df.(app.OutcomeDD.Value)) & app.UniformCkb.Value)
                app.HistogramAxes.Visible = true;
                % Define the number of groups (categories)
                numGroups = value;
                app.fRankingData.outcome = app.fRankingData.df.(app.OutcomeDD.Value);
                X = app.fRankingData.outcome;
                % Compute the quantile thresholds for uniform binning
                thresholds = quantile(X, linspace(0, 1, numGroups + 1));
                
                % Initialize an array to store group indices
                groups = zeros(size(X));
                
                % Assign each value in X to one of the groups
                for i = 1:numGroups
                    if i == numGroups
                        % Include the upper boundary in the last group
                        groups(X >= thresholds(i) & X <= thresholds(i + 1)) = thresholds(i);
                    else
                        groups(X >= thresholds(i) & X < thresholds(i + 1)) = thresholds(i);
                    end
                end
                app.fRankingData.outcome = groups;
                histogram(app.HistogramAxes,categorical(groups),'Categories', categorical(thresholds));
                % axis tight
            else
                app.HistogramAxes.Visible = true;
                app.fRankingData.outcome = app.fRankingData.df.(app.OutcomeDD.Value);
                histogram(app.HistogramAxes,app.fRankingData.df.(app.OutcomeDD.Value),value);
                % axis tight
            end
        end

        % Value changed function: SelectAllPredictorsCkb
        function SelectAllPredictorsCkbValueChanged(app, event)
            if(app.SelectAllPredictorsCkb.Value)
                app.PredictorsLB.Value = app.PredictorsLB.Items;
            else
                app.PredictorsLB.Value = {};
            end
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if(strcmp(app.OutcomeDD.Value, 'Select'))
                uialert(app.UIFigure,'Make sure to select an outcome variable!','error');
                return;
            end
            if(length(app.PredictorsLB.Value)<2)
                uialert(app.UIFigure,'Make sure to select at least 2 predictors!','error');
                return;
            end
            if(length(app.MethodsLB.Value)<1)
                uialert(app.UIFigure,'Make sure to select at least 1 method!','error');
                return;
            end
            methods = app.MethodsLB.Value;
            outcome = app.fRankingData.outcome;
            features = app.fRankingData.df(:,app.PredictorsLB.Value);
            app.RWA.RankFeatures(features,outcome,methods);
            % save("dataRanking.mat","outcome","features")
            
        end

        % Value changed function: SelectAllMethodsCkb
        function SelectAllMethodsCkbValueChanged(app, event)
            if(app.SelectAllMethodsCkb.Value)
                app.MethodsLB.Value = app.MethodsLB.Items;
            else
                app.MethodsLB.Value = app.MethodsLB.Items(1);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HistogramAxes = uiaxes(app.UIFigure);
            title(app.HistogramAxes, 'Outcome Variable Histogram')
            xlabel(app.HistogramAxes, 'Levels')
            ylabel(app.HistogramAxes, 'Frequency')
            zlabel(app.HistogramAxes, 'Z')
            app.HistogramAxes.Visible = 'off';
            app.TransTypeLabel_4 = uilabel(app.UIFigure);
            app.TransTypeLabel_4.Text = 'Region';
            app.RegionTypeDD = uidropdown(app.UIFigure);
            app.RegionTypeDD.Items = {''};
            app.RegionTypeDD.Editable = 'on';
            app.RegionTypeDD.ValueChangedFcn = createCallbackFcn(app, @RegionTypeFcn, true);
            app.RegionTypeDD.Tooltip = {'| symbol can be added between region names (except custom) to include more than one'};
            app.RegionTypeDD.Value = '';
            app.TransTypeLabel_5 = uilabel(app.UIFigure);
            app.TransTypeLabel_5.Text = 'Walk';
            app.WalkTypeDD = uidropdown(app.UIFigure);
            app.WalkTypeDD.Items = {''};
            app.WalkTypeDD.Editable = 'on';
            app.WalkTypeDD.Tooltip = {'A list of walk numbers can be added manually (i.e. 1,2,7)'};
            app.WalkTypeDD.Value = '';
            app.TransTypeLabel_8 = uilabel(app.UIFigure);
            app.TransTypeLabel_8.Text = 'Patient';
            app.PatientTypeDD = uidropdown(app.UIFigure);
            app.PatientTypeDD.Items = {''};
            app.PatientTypeDD.Editable = 'on';
            app.PatientTypeDD.Tooltip = {'A list of patient numbers can be added manually (i.e. 1,3,4)'};
            app.PatientTypeDD.Value = '';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.SaveBtn = uibutton(app.UIFigure, 'push');
            app.SaveBtn.ButtonPushedFcn = createCallbackFcn(app, @SaveBtnFcn, true);
            app.SaveBtn.Text = 'Save';
            app.SaveFigCkb = uicheckbox(app.UIFigure);
            app.SaveFigCkb.Text = 'Fig';
            app.SaveDirLabel = uilabel(app.UIFigure);
            app.SaveDirLabel.Text = 'Save Dir';
            app.SelDirEdit = uieditfield(app.UIFigure, 'text');
            app.RegionCkb = uicheckbox(app.UIFigure);
            app.RegionCkb.Tooltip = {'Iterate and save'};
            app.RegionCkb.Text = '';
            app.PatientCkb = uicheckbox(app.UIFigure);
            app.PatientCkb.Tooltip = {'Iterate and save'};
            app.PatientCkb.Text = '';
            app.WalkCkb = uicheckbox(app.UIFigure);
            app.WalkCkb.Tooltip = {'Iterate and save'};
            app.WalkCkb.Text = '';
            app.SaveRawCkb = uicheckbox(app.UIFigure);
            app.SaveRawCkb.Text = 'Raw';
            app.OutcomeDropDownLabel = uilabel(app.UIFigure);
            app.OutcomeDropDownLabel.Text = 'Outcome';
            app.OutcomeDD = uidropdown(app.UIFigure);
            app.OutcomeDD.Items = {''};
            app.OutcomeDD.ValueChangedFcn = createCallbackFcn(app, @OutcomeChangeFcn, true);
            app.OutcomeDD.Value = '';
            app.LevelsofOutcomeLabel = uilabel(app.UIFigure);
            app.LevelsofOutcomeLabel.Text = '# Levels of Outcome';
            app.LevelsofOutcomeEF = uieditfield(app.UIFigure, 'numeric');
            app.LevelsofOutcomeEF.Limits = [2 100000];
            app.LevelsofOutcomeEF.AllowEmpty = 'on';
            app.LevelsofOutcomeEF.ValueChangedFcn = createCallbackFcn(app, @LevelsofOutcomeEFValueChanged, true);
            app.LevelsofOutcomeEF.Value = [];
            app.SelectAllPredictorsCkb = uicheckbox(app.UIFigure);
            app.SelectAllPredictorsCkb.ValueChangedFcn = createCallbackFcn(app, @SelectAllPredictorsCkbValueChanged, true);
            app.SelectAllPredictorsCkb.Text = 'Select All';
            app.PredictorsListBoxLabel = uilabel(app.UIFigure);
            app.PredictorsListBoxLabel.Text = 'Predictors';
            app.PredictorsLB = uilistbox(app.UIFigure);
            app.PredictorsLB.Items = {};
            app.PredictorsLB.Multiselect = 'on';
            app.PredictorsLB.Value = {};
            app.LevelsTextAreaLabel = uilabel(app.UIFigure);
            app.LevelsTextAreaLabel.Visible = 'off';
            app.LevelsTextAreaLabel.Text = 'Levels';
            app.LevelsTextArea = uitextarea(app.UIFigure);
            app.LevelsTextArea.Editable = 'off';
            app.LevelsTextArea.Visible = 'off';
            app.UniformCkb = uicheckbox(app.UIFigure);
            app.UniformCkb.ValueChangedFcn = createCallbackFcn(app, @LevelsofOutcomeEFValueChanged, true);
            app.UniformCkb.Enable = 'off';
            app.UniformCkb.Text = 'Uniform';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.FeatureSelectionLabel = uilabel(app.UIFigure);
            app.FeatureSelectionLabel.Text = 'Feature Selection:';
            app.SelectAllMethodsCkb = uicheckbox(app.UIFigure);
            app.SelectAllMethodsCkb.ValueChangedFcn = createCallbackFcn(app, @SelectAllMethodsCkbValueChanged, true);
            app.SelectAllMethodsCkb.Text = 'Select All';
            app.SelectAllMethodsCkb.Value = true;
            app.MethodsLabel = uilabel(app.UIFigure);
            app.MethodsLabel.Text = 'Methods:';
            app.MethodsLB = uilistbox(app.UIFigure);
            app.MethodsLB.Items = {'MRMR', 'Chi-Square'};
            app.MethodsLB.Multiselect = 'on';
            app.MethodsLB.Value = {'MRMR', 'Chi-Square'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MultSegFRankingGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end