classdef MultSegGLMEGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        InterceptCkb      matlab.ui.control.CheckBox
        ClearBtn          matlab.ui.control.Button
        AddREBtn          matlab.ui.control.Button
        AddFEBtn          matlab.ui.control.Button
        Label             matlab.ui.control.Label
        PredDD            matlab.ui.control.DropDown
        RespDD            matlab.ui.control.DropDown
        GLMEMdl           matlab.ui.control.EditField
        SaveDirLabel_2    matlab.ui.control.Label
        SaveRawCkb        matlab.ui.control.CheckBox
        WalkCkb           matlab.ui.control.CheckBox
        PatientCkb        matlab.ui.control.CheckBox
        RegionCkb         matlab.ui.control.CheckBox
        SelDirEdit        matlab.ui.control.EditField
        SaveDirLabel      matlab.ui.control.Label
        SaveFigCkb        matlab.ui.control.CheckBox
        SaveBtn           matlab.ui.control.Button
        PlotButton        matlab.ui.control.Button
        PatientTypeDD     matlab.ui.control.DropDown
        TransTypeLabel_8  matlab.ui.control.Label
        WalkTypeDD        matlab.ui.control.DropDown
        TransTypeLabel_5  matlab.ui.control.Label
        RegionTypeDD      matlab.ui.control.DropDown
        TransTypeLabel_4  matlab.ui.control.Label
    end

    
    properties (Access = public)
        RWA % realworld analysis object
        fH; %glme results
        CustomRegion1;
        aH; %axis handle 
        FECell; %fixed effects
        RECell; %random effects
    end
    
    methods (Access = private)
        
        function updateMdl(app,varargin)
            if isempty(app.RespDD.Value)
                app.GLMEMdl.Value = '';
            else
                mdlstr = [app.RespDD.Value,' ~ '];
                if ~app.InterceptCkb.Value
                    mdlstr = [mdlstr,'-1 + '];
                end
                for k=1:length(app.FECell)
                    mdlstr = [mdlstr,app.FECell{k},' + '];
                end
                for k=1:length(app.RECell)
                    mdlstr = [mdlstr,'(1|',app.RECell{k},') + '];
                end
                mdlstr = regexprep(mdlstr,'(\s*\+\s*)$','');
                app.GLMEMdl.Value = mdlstr;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin>1
                app.RWA = varargin{1};
                if isempty(app.RWA.MultSeg)
                    uialert(app.UIFigure,'Run getMultSegData first!','warning');
                    return;
                end
            else
                WaitH = uiprogressdlg(app.UIFigure,'Indeterminate','on','Message','Loading...');
                drawnow;
                pause(0.1);
                app.RWA = RWAnalysis2;
                app.RWA.loadMultData;
                close(WaitH);
                if isempty(app.RWA.MultSeg)
                    uialert(app.UIFigure,'RWAnalysis.mat file not found!','warning');
                    return;
                end
            end
            
            %regions
            regionlist = {'AntHipp','LatTemp','Ent+Peri','PostHipp+Para','All Chans','Custom'};
            app.RegionTypeDD.Items = [{''},regionlist]; app.RegionTypeDD.ValueIndex = 1;

            %patient
            patientlist = {'All Patients','Leave Out 1','Leave Out 2','Leave Out 3','Leave Out 4','Leave Out 5','1','2','3','4','5'};
            app.PatientTypeDD.Items = [{''},patientlist]; app.PatientTypeDD.Value = 'All Patients';

            %walk
            walklist = {'First Walks','Last Walks','Stop Walks','Go Walks','All Walks','1','2','3','4','5','6','7','8'};
            app.WalkTypeDD.Items = [{''},walklist]; app.WalkTypeDD.Value = 'All Walks';

            %response
            resplist = app.RWA.MultSeg.SegTable.Properties.VariableNames;
            app.RespDD.Items = [{''},resplist]; app.RespDD.Value = '';

            %predictors
            predlist = app.RWA.MultSeg.SegTable.Properties.VariableNames;
            app.PredDD.Items = [{''},predlist]; app.PredDD.Value = '';

            app.FECell = {};
            app.RECell = {};
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            regiontype = app.RegionTypeDD.Value;
            patienttype = app.PatientTypeDD.Value;
            walktype = app.WalkTypeDD.Value;
            customregion = app.CustomRegion1;
            glmemdl = app.GLMEMdl.Value;
           
            b = [];
            b(1) = isempty(regiontype) && ~app.RegionCkb.Value;
            b(2) = isempty(patienttype) && ~app.PatientCkb.Value;
            b(3) = isempty(walktype) && ~app.WalkCkb.Value;
            b(4) = strcmp(regiontype,'Custom') && isempty(customregion);         

            %Perform some checks
            if any(b)
                uialert(app.UIFigure,'Make sure to select all options!','error');
                return;
            end           

            %Plot
            if app.RegionCkb.Value
                regioncell = app.RegionTypeDD.Items(2:end-1);
            else
                regioncell = {regiontype};
            end
            if app.PatientCkb.Value
                patientcell = app.PatientTypeDD.Items(2:end);
            else
                patientcell = {patienttype};
            end
            if app.WalkCkb.Value
                walkcell = app.WalkTypeDD.Items(2:end);
            else
                walkcell = {walktype};
            end
           
            nregion = length(regioncell);
            npatient = length(patientcell);
            nwalk = length(walkcell);
            ntotal = nregion*npatient*nwalk;

            [ridx,pidx,widx] = ndgrid(1:nregion,1:npatient,1:nwalk);
            idx = [ridx(:),pidx(:),widx(:)];

            if size(idx,1)~=ntotal
                uialert(app.UIFigure,'Iteration size mismatch!','error');
                return;
            end

            iterflag = any([nregion,npatient,nwalk,ntotal]>1);
            if iterflag && (app.SaveFigCkb.Value || app.SaveRawCkb.Value)
                uialert(app.UIFigure,'Uncheck "Raw" or "Fig"','error');
                return;
            end

            if iterflag
                sel = uiconfirm(app.UIFigure,sprintf('%d iterations will be performed and saved. Continue?',ntotal),'Continue?');
                switch sel
                    case 'Cancel'
                        return;
                end
            end

            pb = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Generating plot...');
            for k=1:ntotal
                pb.Value = (k/ntotal);
                % if any(ishandle(app.fH)); close(app.fH); end
                app.fH = app.RWA.plotMultGLME('regiontype',regioncell{idx(k,1)},'walktype',walkcell{idx(k,3)},...
                    'patienttype',patientcell{idx(k,2)},'customregion',customregion,'glmemdl',glmemdl);
                drawnow; pause(0.1);
                if iterflag
                    app.SaveBtnFcn;
                end
            end
            close(pb);


        end

        % Button pushed function: SaveBtn
        function SaveBtnFcn(app, event)
            if any(ishandle(app.fH))
                app.SaveBtn.BackgroundColor = 'r';
                if isempty(app.SelDirEdit.Value)
                    app.SelDirEdit.Value = lastDir('Choose the destination folder...');
                end
                if ~isfolder(app.SelDirEdit.Value)
                    mkdir(app.SelDirEdit.Value);
                end
                tStr = app.fH.Name;
                tStr = regexprep(tStr,'\s+','');
                tStr = regexprep(tStr,'\\|/','-');
                tStr = regexprep(tStr,'<','');
                tStr = regexprep(tStr,'::','~');
                tStr = regexprep(tStr,'\|','');
                tStr = regexprep(tStr,'\(',',');
                tStr = regexprep(tStr,'\)','');
                tStr = regexprep(tStr,'=','');
                
                fstr = sprintf('GLMEGUI_%s.png',tStr);
                print(app.fH,fullfile(app.SelDirEdit.Value,fstr),'-dpng','-r300');
                if app.SaveFigCkb.Value
                    savefig(app.fH,fullfile(app.SelDirEdit.Value,regexprep(fstr,'png$','fig')))
                end
                if app.SaveRawCkb.Value
                    MS = app.RWA.MultSeg.MS;
                    save(fullfile(app.SelDirEdit.Value,regexprep(fstr,'png$','mat')),"MS",'-v7.3')
                end
                
                app.SaveBtn.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: RegionTypeDD
        function RegionTypeFcn(app, event)
            if strcmp(app.RegionTypeDD.Value,'Custom')
                CustomRegionGUI(app,1);
            end            
        end

        % Button pushed function: AddFEBtn
        function AddFEBtnFcn(app, event)
            app.FECell = cat(1,app.FECell,app.PredDD.Value);
            app.updateMdl;
        end

        % Button pushed function: AddREBtn
        function AddREBtnFcn(app, event)
            app.RECell = cat(1,app.RECell,app.PredDD.Value);
            app.updateMdl;
        end

        % Button pushed function: ClearBtn
        function ClearBtnFcn(app, event)
            app.FECell = {};
            app.RECell = {};
            app.updateMdl;
        end

        % Value changed function: RespDD
        function RespDDFcn(app, event)
            app.updateMdl;
        end

        % Value changed function: InterceptCkb
        function InterceptCkbFcn(app, event)
            app.updateMdl;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TransTypeLabel_4 = uilabel(app.UIFigure);
            app.TransTypeLabel_4.Text = 'Region';
            app.RegionTypeDD = uidropdown(app.UIFigure);
            app.RegionTypeDD.Items = {''};
            app.RegionTypeDD.Editable = 'on';
            app.RegionTypeDD.ValueChangedFcn = createCallbackFcn(app, @RegionTypeFcn, true);
            app.RegionTypeDD.Tooltip = {'| symbol can be added between region names (except custom) to include more than one'};
            app.RegionTypeDD.Value = '';
            app.TransTypeLabel_5 = uilabel(app.UIFigure);
            app.TransTypeLabel_5.Text = 'Walk';
            app.WalkTypeDD = uidropdown(app.UIFigure);
            app.WalkTypeDD.Items = {''};
            app.WalkTypeDD.Editable = 'on';
            app.WalkTypeDD.Tooltip = {'A list of walk numbers can be added manually (i.e. 1,2,7)'};
            app.WalkTypeDD.Value = '';
            app.TransTypeLabel_8 = uilabel(app.UIFigure);
            app.TransTypeLabel_8.Text = 'Patient';
            app.PatientTypeDD = uidropdown(app.UIFigure);
            app.PatientTypeDD.Items = {''};
            app.PatientTypeDD.Editable = 'on';
            app.PatientTypeDD.Tooltip = {'A list of patient numbers can be added manually (i.e. 1,3,4)'};
            app.PatientTypeDD.Value = '';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SaveBtn = uibutton(app.UIFigure, 'push');
            app.SaveBtn.ButtonPushedFcn = createCallbackFcn(app, @SaveBtnFcn, true);
            app.SaveBtn.Text = 'Save';
            app.SaveFigCkb = uicheckbox(app.UIFigure);
            app.SaveFigCkb.Text = 'Fig';
            app.SaveDirLabel = uilabel(app.UIFigure);
            app.SaveDirLabel.Text = 'Save Dir';
            app.SelDirEdit = uieditfield(app.UIFigure, 'text');
            app.RegionCkb = uicheckbox(app.UIFigure);
            app.RegionCkb.Tooltip = {'Iterate and save'};
            app.RegionCkb.Text = '';
            app.PatientCkb = uicheckbox(app.UIFigure);
            app.PatientCkb.Tooltip = {'Iterate and save'};
            app.PatientCkb.Text = '';
            app.WalkCkb = uicheckbox(app.UIFigure);
            app.WalkCkb.Tooltip = {'Iterate and save'};
            app.WalkCkb.Text = '';
            app.SaveRawCkb = uicheckbox(app.UIFigure);
            app.SaveRawCkb.Text = 'Raw';
            app.SaveDirLabel_2 = uilabel(app.UIFigure);
            app.SaveDirLabel_2.Text = 'GLME Model';
            app.GLMEMdl = uieditfield(app.UIFigure, 'text');
            app.RespDD = uidropdown(app.UIFigure);
            app.RespDD.Items = {''};
            app.RespDD.Editable = 'on';
            app.RespDD.ValueChangedFcn = createCallbackFcn(app, @RespDDFcn, true);
            app.RespDD.Tooltip = {'A list of walk numbers can be added manually (i.e. 1,2,7)'};
            app.RespDD.Value = '';
            app.PredDD = uidropdown(app.UIFigure);
            app.PredDD.Items = {''};
            app.PredDD.Editable = 'on';
            app.PredDD.Tooltip = {'A list of walk numbers can be added manually (i.e. 1,2,7)'};
            app.PredDD.Value = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '~';
            app.AddFEBtn = uibutton(app.UIFigure, 'push');
            app.AddFEBtn.ButtonPushedFcn = createCallbackFcn(app, @AddFEBtnFcn, true);
            app.AddFEBtn.Text = 'Add FE';
            app.AddREBtn = uibutton(app.UIFigure, 'push');
            app.AddREBtn.ButtonPushedFcn = createCallbackFcn(app, @AddREBtnFcn, true);
            app.AddREBtn.Text = 'Add RE';
            app.ClearBtn = uibutton(app.UIFigure, 'push');
            app.ClearBtn.ButtonPushedFcn = createCallbackFcn(app, @ClearBtnFcn, true);
            app.ClearBtn.Text = 'Clear';
            app.InterceptCkb = uicheckbox(app.UIFigure);
            app.InterceptCkb.ValueChangedFcn = createCallbackFcn(app, @InterceptCkbFcn, true);
            app.InterceptCkb.Text = 'Intercept';
            app.InterceptCkb.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MultSegGLMEGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end