classdef RasterPlotGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlotAverageCorrelationMatrixPanel  matlab.ui.container.Panel
        PlotCorrMatButton               matlab.ui.control.Button
        WalksEditField                  matlab.ui.control.EditField
        WalksEditFieldLabel             matlab.ui.control.Label
        PatientsEditField               matlab.ui.control.EditField
        PtientsEditFieldLabel           matlab.ui.control.Label
        PlotReferenceVariableCheckBox   matlab.ui.control.CheckBox
        CloseAllButton                  matlab.ui.control.Button
        NotPairwiseCorrCheckBox         matlab.ui.control.CheckBox
        ReferenceVariableDropDown       matlab.ui.control.DropDown
        ReferenceVariableDropDownLabel  matlab.ui.control.Label
        SortDropDown                    matlab.ui.control.DropDown
        SortDropDownLabel               matlab.ui.control.Label
        DendogramCheckBox               matlab.ui.control.CheckBox
        CorrelationMatrixCheckBox       matlab.ui.control.CheckBox
        RemoveEndTransitionsCheckBox    matlab.ui.control.CheckBox
        RemoveBeginTransitionsCheckBox  matlab.ui.control.CheckBox
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        DescriptionLB                   matlab.ui.control.ListBox
        TransitionsLabel_2              matlab.ui.control.Label
        SelectAllDescriptionCkb         matlab.ui.control.CheckBox
        TransitionsLB                   matlab.ui.control.ListBox
        TransitionsLabel                matlab.ui.control.Label
        SelectAllTransitionsCkb         matlab.ui.control.CheckBox
        SaveRawCkb                      matlab.ui.control.CheckBox
        WalkCkb                         matlab.ui.control.CheckBox
        PatientCkb                      matlab.ui.control.CheckBox
        SelDirEdit                      matlab.ui.control.EditField
        SaveDirLabel                    matlab.ui.control.Label
        SaveFigCkb                      matlab.ui.control.CheckBox
        SaveBtn                         matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        PatientTypeDD                   matlab.ui.control.DropDown
        TransTypeLabel_8                matlab.ui.control.Label
        WalkTypeDD                      matlab.ui.control.DropDown
        TransTypeLabel_5                matlab.ui.control.Label
    end

    
    properties (Access = public)
        RWdat % realworld analysis object
    end
    
    methods (Access = private)
        
        function LoadNewData(app)
            app.RWdat.pID = app.PatientTypeDD.ValueIndex;
            app.RWdat.wID = app.WalkTypeDD.ValueIndex;
            dat = load("RWNApp_RW"+app.RWdat.pID+"_Walk"+app.RWdat.wID+".mat");
            app.RWdat.allVars = dat;
            app.RWdat.evTable = dat.evnts_tbl;
            
            UpdateTransitionDescription(app);
        end
        
        function UpdateTransitionDescription(app)
            evCounts = groupsummary(app.RWdat.evTable,"Event");
            keepEvents = true(length(evCounts.Event),1);
            if(app.RemoveBeginTransitionsCheckBox.Value)
                keepEvents = ~contains(evCounts.Event,"Beg","IgnoreCase",true) & keepEvents;
            end
            if(app.RemoveEndTransitionsCheckBox.Value)
                keepEvents = ~contains(evCounts.Event,"End","IgnoreCase",true) & keepEvents;
            end
            evUnique = evCounts.Event(evCounts.GroupCount>=app.ThresholdEditField.Value & keepEvents);
            
            app.TransitionsLB.Items = evUnique;
            app.RWdat.evTable.Include = ismember(app.RWdat.evTable.Event,evUnique);

            [~, descriptionUnique] = TextProcess.ExtractDescriptions(app.RWdat.evTable.Description(app.RWdat.evTable.Include), '\[([^\]]+)\]');

            app.DescriptionLB.Items = descriptionUnique;

            app.SelectAllTransitionsCkb.Value = true;
            app.SelectAllDescriptionCkb.Value = true;
            app.TransitionsLB.Value = app.TransitionsLB.Items;
            app.DescriptionLB.Value = app.DescriptionLB.Items;
        end
        
        function varNameList = FindDataVariables(app,varNameCode)
            allVarStruct = app.RWdat.allVars;
            fieldNames = fieldnames(allVarStruct);
            fieldNames = fieldNames(contains(fieldNames,'d_'));
            varNameList = [];
            % Loop through all vars to find those starting with "d_"
            for i = 1:numel(fieldNames)
                fieldName = fieldNames{i};
                if startsWith(fieldName, varNameCode) % Check if the field name starts with "d_"
                    newName = extractAfter(fieldName, varNameCode); % Remove "d_"
                    value = allVarStruct.(fieldName); % Access the field's value
                    % Check if the variable is empty
                    % Currently only tables and arrays are counted
                    if (~isempty(value))
                        if(istable(value))
                            for inerIdx=1:length(value.Properties.VariableNames)
                                varNameList = cat(1,varNameList,string(newName)+"."+string(value.Properties.VariableNames{inerIdx}));    
                            end
                        elseif(size(value,2)==1) 
                            varNameList = cat(1,varNameList,string(newName));
                        end
                        
                    end
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % Automatically read the patient list and walk from the file
            % names based on ..RWX_WalkY.mat
            files = dir('*.mat');
            fileNames = {files.name};
            tokens = TextProcess.ExtractPatientWalkFromFileNames(fileNames, 'RW(\d+)_Walk(\d+)\.mat');
            if isempty(tokens) % Check if patient files were found
                disp('No patient file has been found');
                folderPath = uigetdir(pwd, 'Select the folder that contains individual data');
                if folderPath ~= 0 % If the user selects a folder
                    files = dir(fullfile(folderPath,'*.mat'));
                    fileNames = {files.name};
                    tokens = TextProcess.ExtractPatientWalkFromFileNames(fileNames, 'RW(\d+)_Walk(\d+)\.mat');
                    addpath(genpath(folderPath));
                else
                    error('Patient files not found');
                end
            end
            app.RWdat.patientNumbers = tokens(:,1);
            app.RWdat.walkNumbers = tokens(:,2);

            app.PatientTypeDD.Items = string(unique(app.RWdat.patientNumbers));
            app.PatientTypeDD.ValueIndex = 1;
            app.WalkTypeDD.Items = string(unique(app.RWdat.walkNumbers(app.RWdat.patientNumbers == app.PatientTypeDD.ValueIndex)));

            LoadNewData(app);           
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            %%%%%%%%%%%%%%%% ---------------------------------> Change
            %%%%%%%%%%%%%%%% Transision.items to Values
            evUnique = app.TransitionsLB.Value;
            uniqueDescrip = app.DescriptionLB.Value;
            orderStr = app.SortDropDown.Value;

            app.RWdat.evTable.Include = ismember(app.RWdat.evTable.Event,evUnique);
            evTable = app.RWdat.evTable(app.RWdat.evTable.Include,:);
            [evTable.Description, descriptionUnique] = TextProcess.ExtractDescriptions(evTable.Description, '\[([^\]]+)\]');
            
            %------ process descriptions
            for desIdx = 1:length(uniqueDescrip)
                evTable.(uniqueDescrip{desIdx}) = contains(evTable.Description,uniqueDescrip{desIdx});
            end
            % for evIdx = 1:length(evUnique)
            %     tTemp = Abnormal
            % end


            %=============== end process descriptions

            tStart = app.RWdat.evTable.NTP(1);
            % tEnd = app.RWdat.evTable.NTP(end)/60*1000;
            timeVals = (evTable.NTP-tStart)/60*1000;
            tArray = (0:timeVals(end))';
            tIdx = knnsearch(tArray,timeVals);
            
            % Find Event Time Series
            evTimeSeries = zeros(length(tArray),length(evUnique));
            evTimeSeries2 = zeros(length(tArray),length(evUnique));
            for evIdx = 1:length(evUnique)
                evName = string(evUnique{evIdx});
                evTimeSeries(tIdx,evIdx) = strcmp(evTable.Event,evName);
                evTimeSeries2(:,evIdx) = conv(evTimeSeries(:,evIdx),hamming(20),'same');
                evTimeSeries2(:,evIdx) = evTimeSeries2(:,evIdx)/max(evTimeSeries2(:,evIdx));
            end

            if(~app.NotPairwiseCorrCheckBox.Value)
                % -------------- Compute pairwise Correlations
                corrMat = corr(evTimeSeries2);
                corrMat(eye(size(corrMat))==1)=NaN;
                sumCorr = sum(abs(corrMat),2,"omitmissing");
                if(strcmp(orderStr,'none'))
                    order = 1:length(sumCorr);
                else
                    [~, order] = sort(sumCorr, orderStr);
                end
            else
                % -------------- Correlation with the desire variable
                % Extract the reference variable
                refVarName = string(app.ReferenceVariableDropDown.Value);
                eval("refVarVal = app.RWdat.allVars."+"d_"+refVarName+";")
                if(contains(refVarName,"."))
                    eval("refVarNTP = app.RWdat.allVars."+"ntp_"+extractBefore(refVarName,".")+";")
                else
                    eval("refVarNTP = app.RWdat.allVars."+"ntp_"+refVarName+";")
                end
                refVarNTP = (refVarNTP - tStart)/60;

                % Find the corresponding timepoints and values
                tIdx = knnsearch(refVarNTP,tArray);
                refVarVal = fillmissing(refVarVal,'linear');
                refVarVal = zscore(refVarVal);
                % Replace outliers
                refVarVal(refVarVal>3) = 3;
                refVarVal(refVarVal<-3) = -3;
                refVarVal = refVarVal(tIdx);

                % -------------- Compute Correlations
                corrMat = corr(evTimeSeries,refVarVal);
                if(strcmp(orderStr,'none'))
                    order = 1:length(corrMat);
                else
                    [~, order] = sort(corrMat, orderStr);
                end
            end

            
            figure;
            ax = subplot(2,1,1);
            % evTimeSeries = evTimeSeries(:,order);
            yVals = 1:length(evUnique);
            xLabel = "time(min)";
            xVals = tArray/1000;
            % ax = axes(figure);
            Plot.RasterPlot(ax, evUnique(order), evTable, yVals, xVals, xLabel, evTimeSeries2);
            % Plot.RasterPlot(ax, evUnique(order), evTable, yVals, xVals, xLabel, evTimeSeries);
            if(app.NotPairwiseCorrCheckBox.Value && app.PlotReferenceVariableCheckBox.Value)
                plot(ax, tArray, refVarVal-1,'k')
                ylim([min(refVarVal-1),length(evUnique)+1])
            end

            ax2 = subplot(2,2,3);
            treeMat = linkage(evTimeSeries',"average",'correlation');
            % leafOrder = optimalleaforder(tree,order);
            % tree = linkage(X,"average");
            dendrogram(ax2, treeMat,'Labels', evUnique, Reorder = order,Orientation="right",ColorThreshold="default");


            ax3 = subplot(2,2,4);
            if(size(corrMat,2)==1)
                imagesc(ax3,corrMat); % Display the matrix as an image
                colorbar; % Add a colorbar for reference
                clim([-1, 1]); % Set color limits for correlation values (-1 to 1)
                colormap(ax3,winter); % Set the colormap
                
                % Customize the axes
                xticks(1); % Set x-axis ticks
                yticks(1:length(evUnique)); % Set y-axis ticks
                xticklabels(refVarName); % Label x-axis ticks with variable names
                yticklabels(evUnique); % Label y-axis ticks with variable names
                title('Correlation Matrix');
            else
                corrMat(eye(size(corrMat))==1)=NaN;
                imagesc(ax3,corrMat); % Display the matrix as an image
                colorbar; % Add a colorbar for reference
                clim([-1, 1]); % Set color limits for correlation values (-1 to 1)
                colormap(ax3,winter); % Set the colormap
                
                % Customize the axes
                xticks(1:length(evUnique)); % Set x-axis ticks
                yticks(1:length(evUnique)); % Set y-axis ticks
                xticklabels(evUnique); % Label x-axis ticks with variable names
                yticklabels(evUnique); % Label y-axis ticks with variable names
                title('Correlation Matrix');
                axis equal
            end

        end

        % Button pushed function: SaveBtn
        function SaveBtnFcn(app, event)
            
        end

        % Value changed function: PatientTypeDD
        function PatientTypeDDValueChanged(app, event)
            app.WalkTypeDD.Items = string(unique(app.RWdat.walkNumbers(app.RWdat.patientNumbers == app.PatientTypeDD.ValueIndex)));
        end

        % Value changed function: WalkTypeDD
        function WalkTypeDDValueChanged(app, event)
            app.WalkTypeDD.Items = string(unique(app.RWdat.walkNumbers(app.RWdat.patientNumbers == app.PatientTypeDD.ValueIndex)));
            LoadNewData(app);
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            UpdateTransitionDescription(app);
        end

        % Value changed function: SelectAllTransitionsCkb
        function SelectAllTransitionsCkbValueChanged(app, event)
            if(app.SelectAllTransitionsCkb.Value)
                app.TransitionsLB.Value = app.TransitionsLB.Items;
            else
                app.TransitionsLB.Value = app.TransitionsLB.Items(1);
            end
            
        end

        % Value changed function: SelectAllDescriptionCkb
        function SelectAllDescriptionCkbValueChanged(app, event)
            if(app.SelectAllDescriptionCkb.Value)
                app.DescriptionLB.Value = app.DescriptionLB.Items;
            else
                app.DescriptionLB.Value = app.DescriptionLB.Items(1);
            end
            
        end

        % Value changed function: RemoveBeginTransitionsCheckBox
        function RemoveBeginTransitionsCheckBoxValueChanged(app, event)
            UpdateTransitionDescription(app);            
        end

        % Value changed function: RemoveEndTransitionsCheckBox
        function RemoveEndTransitionsCheckBoxValueChanged(app, event)
            UpdateTransitionDescription(app);
        end

        % Value changed function: NotPairwiseCorrCheckBox
        function NotPairwiseCorrCheckBoxValueChanged(app, event)
            if(app.NotPairwiseCorrCheckBox.Value)
                varNameList = FindDataVariables(app,'d_');
                if(~isempty(varNameList))
                    app.ReferenceVariableDropDown.Items = varNameList;
                    app.ReferenceVariableDropDown.ValueIndex = 1;
                    app.ReferenceVariableDropDown.Enable = true;
                    app.PlotReferenceVariableCheckBox.Enable = true;
                    app.PlotReferenceVariableCheckBox.Value = true;
                end
            else
                app.ReferenceVariableDropDown.Items = "pairwise";
                app.ReferenceVariableDropDown.Value = "pairwise";
                app.ReferenceVariableDropDown.Enable = false;
                app.PlotReferenceVariableCheckBox.Enable = false;
                app.PlotReferenceVariableCheckBox.Value = false;
            end
            
        end

        % Button pushed function: CloseAllButton
        function CloseAllButtonPushed(app, event)
            close all
        end

        % Button pushed function: PlotCorrMatButton
        function PlotCorrMatButtonPushed(app, event)
            evUnique = app.TransitionsLB.Value;
            Patients = str2num(app.PatientsEditField.Value);
            Walks = str2num(app.WalksEditField.Value);
            Plot.PlotAverageCorrelationMatrix(evUnique,Walks,Patients)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TransTypeLabel_5 = uilabel(app.UIFigure);
            app.TransTypeLabel_5.Text = 'Walk';
            app.WalkTypeDD = uidropdown(app.UIFigure);
            app.WalkTypeDD.Items = {''};
            app.WalkTypeDD.Editable = 'on';
            app.WalkTypeDD.ValueChangedFcn = createCallbackFcn(app, @WalkTypeDDValueChanged, true);
            app.WalkTypeDD.Tooltip = {'A list of walk numbers can be added manually (i.e. 1,2,7)'};
            app.WalkTypeDD.Value = '';
            app.TransTypeLabel_8 = uilabel(app.UIFigure);
            app.TransTypeLabel_8.Text = 'Patient';
            app.PatientTypeDD = uidropdown(app.UIFigure);
            app.PatientTypeDD.Items = {''};
            app.PatientTypeDD.Editable = 'on';
            app.PatientTypeDD.ValueChangedFcn = createCallbackFcn(app, @PatientTypeDDValueChanged, true);
            app.PatientTypeDD.Tooltip = {'A list of patient numbers can be added manually (i.e. 1,3,4)'};
            app.PatientTypeDD.Value = '';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SaveBtn = uibutton(app.UIFigure, 'push');
            app.SaveBtn.ButtonPushedFcn = createCallbackFcn(app, @SaveBtnFcn, true);
            app.SaveBtn.Text = 'Save';
            app.SaveFigCkb = uicheckbox(app.UIFigure);
            app.SaveFigCkb.Text = 'Fig';
            app.SaveDirLabel = uilabel(app.UIFigure);
            app.SaveDirLabel.Text = 'Save Dir';
            app.SelDirEdit = uieditfield(app.UIFigure, 'text');
            app.PatientCkb = uicheckbox(app.UIFigure);
            app.PatientCkb.Tooltip = {'Iterate and save'};
            app.PatientCkb.Text = '';
            app.WalkCkb = uicheckbox(app.UIFigure);
            app.WalkCkb.Tooltip = {'Iterate and save'};
            app.WalkCkb.Text = '';
            app.SaveRawCkb = uicheckbox(app.UIFigure);
            app.SaveRawCkb.Text = 'Raw';
            app.SelectAllTransitionsCkb = uicheckbox(app.UIFigure);
            app.SelectAllTransitionsCkb.ValueChangedFcn = createCallbackFcn(app, @SelectAllTransitionsCkbValueChanged, true);
            app.SelectAllTransitionsCkb.Text = 'Select All';
            app.TransitionsLabel = uilabel(app.UIFigure);
            app.TransitionsLabel.Text = 'Transitions:';
            app.TransitionsLB = uilistbox(app.UIFigure);
            app.TransitionsLB.Items = {};
            app.TransitionsLB.Multiselect = 'on';
            app.TransitionsLB.Value = {};
            app.SelectAllDescriptionCkb = uicheckbox(app.UIFigure);
            app.SelectAllDescriptionCkb.ValueChangedFcn = createCallbackFcn(app, @SelectAllDescriptionCkbValueChanged, true);
            app.SelectAllDescriptionCkb.Text = 'Select All';
            app.TransitionsLabel_2 = uilabel(app.UIFigure);
            app.TransitionsLabel_2.Text = 'Description:';
            app.DescriptionLB = uilistbox(app.UIFigure);
            app.DescriptionLB.Items = {};
            app.DescriptionLB.Multiselect = 'on';
            app.DescriptionLB.Value = {};
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Tooltip = {'Threshold for the frequency of the transitions to be >= threshold'};
            app.ThresholdEditField.Value = 5;
            app.RemoveBeginTransitionsCheckBox = uicheckbox(app.UIFigure);
            app.RemoveBeginTransitionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemoveBeginTransitionsCheckBoxValueChanged, true);
            app.RemoveBeginTransitionsCheckBox.Text = 'Remove Begin';
            app.RemoveEndTransitionsCheckBox = uicheckbox(app.UIFigure);
            app.RemoveEndTransitionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemoveEndTransitionsCheckBoxValueChanged, true);
            app.RemoveEndTransitionsCheckBox.Text = 'Remove End';
            app.RemoveEndTransitionsCheckBox.Value = true;
            app.CorrelationMatrixCheckBox = uicheckbox(app.UIFigure);
            app.CorrelationMatrixCheckBox.Tooltip = {'Show Correlation Matrix'};
            app.CorrelationMatrixCheckBox.Text = 'Correlation Matrix';
            app.CorrelationMatrixCheckBox.Value = true;
            app.DendogramCheckBox = uicheckbox(app.UIFigure);
            app.DendogramCheckBox.Tooltip = {'Show Dendogram'};
            app.DendogramCheckBox.Text = 'Dendogram';
            app.DendogramCheckBox.Value = true;
            app.SortDropDownLabel = uilabel(app.UIFigure);
            app.SortDropDownLabel.Text = 'Sort';
            app.SortDropDown = uidropdown(app.UIFigure);
            app.SortDropDown.Items = {'ascend', 'descend', 'none'};
            app.SortDropDown.Value = 'ascend';
            app.ReferenceVariableDropDownLabel = uilabel(app.UIFigure);
            app.ReferenceVariableDropDownLabel.Text = 'Reference Variable';
            app.ReferenceVariableDropDown = uidropdown(app.UIFigure);
            app.ReferenceVariableDropDown.Items = {'pairwise'};
            app.ReferenceVariableDropDown.Enable = 'off';
            app.ReferenceVariableDropDown.Tooltip = {'Compute correlations with the specified measure'};
            app.ReferenceVariableDropDown.Value = 'pairwise';
            app.NotPairwiseCorrCheckBox = uicheckbox(app.UIFigure);
            app.NotPairwiseCorrCheckBox.ValueChangedFcn = createCallbackFcn(app, @NotPairwiseCorrCheckBoxValueChanged, true);
            app.NotPairwiseCorrCheckBox.Tooltip = {'Check if you want to specify another variable as the reference for correlations rather than pairwise correlation.'};
            app.NotPairwiseCorrCheckBox.Text = '';
            app.CloseAllButton = uibutton(app.UIFigure, 'push');
            app.CloseAllButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAllButtonPushed, true);
            app.CloseAllButton.Text = 'Close All';
            app.PlotReferenceVariableCheckBox = uicheckbox(app.UIFigure);
            app.PlotReferenceVariableCheckBox.Enable = 'off';
            app.PlotReferenceVariableCheckBox.Text = '   Plot Reference Variable';
            app.PlotAverageCorrelationMatrixPanel = uipanel(app.UIFigure);
            app.PlotAverageCorrelationMatrixPanel.Title = 'Plot Average Correlation Matrix';
            app.PtientsEditFieldLabel = uilabel(app.PlotAverageCorrelationMatrixPanel);
            app.PtientsEditFieldLabel.Text = 'Ptients:';
            app.PatientsEditField = uieditfield(app.PlotAverageCorrelationMatrixPanel, 'text');
            app.PatientsEditField.Value = '[1,2,3,4,5]';
            app.WalksEditFieldLabel = uilabel(app.PlotAverageCorrelationMatrixPanel);
            app.WalksEditFieldLabel.Text = 'Walks';
            app.WalksEditField = uieditfield(app.PlotAverageCorrelationMatrixPanel, 'text');
            app.WalksEditField.Value = '[1,2,3,4,5,6]';
            app.PlotCorrMatButton = uibutton(app.PlotAverageCorrelationMatrixPanel, 'push');
            app.PlotCorrMatButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCorrMatButtonPushed, true);
            app.PlotCorrMatButton.Text = 'Plot CorrMat';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RasterPlotGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end