classdef SpecGramVideoGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LFP2Vel3Fix4KDE5Amb6Eda7PeT8PeA9PeB10HT11FELabel  matlab.ui.control.Label
        PredictorList12310Label  matlab.ui.control.Label
        PredIdxEdit              matlab.ui.control.EditField
        SelDirEdit               matlab.ui.control.EditField
        SaveDirLabel             matlab.ui.control.Label
        OpenTableButton          matlab.ui.control.Button
        PatientTypeDD            matlab.ui.control.DropDown
        TransTypeLabel_8         matlab.ui.control.Label
        WalkTypeDD               matlab.ui.control.DropDown
        TransTypeLabel_5         matlab.ui.control.Label
        TransTypeDD              matlab.ui.control.DropDown
        TransTypeLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        RWA % realworld analysis object
        fH; %specgram
        aH; %axis handle to specgram (left) or pred plot (right) showing vel/fix/kde/amb
    end
    
    methods (Access = private)
        
        function evntSelFcn(app, varargin)
            evntnum = varargin{2}.InteractionInformation.DisplayRow;

            tr = varargin{3}.transtype;
            pt = str2double(varargin{3}.patienttype);
            wk = str2double(varargin{3}.walktype);
            predidxs = str2num(app.PredIdxEdit.Value);

            pb = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Generating plot...','Indeterminate','on');
            app.fH = app.RWA.plotSpecGramVideoGUI('patient',pt,'walknum',wk,'evntnum',evntnum,'transtype',tr,'savepath',app.SelDirEdit.Value,'predidxs',predidxs);
            close(pb);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin>1
                app.RWA = varargin{1};
                if isempty(app.RWA.MultTrans)
                    uialert(app.UIFigure,'Run getMultTransData first!','warning');
                    return;
                end
            else
                WaitH = uiprogressdlg(app.UIFigure,'Indeterminate','on','Message','Loading...');
                drawnow;
                pause(0.1);
                app.RWA = RWAnalysis2;
                app.RWA.loadMultData;
                close(WaitH);
                if isempty(app.RWA.MultTrans)
                    uialert(app.UIFigure,'RWAnalysis.mat file not found!','warning');
                    return;
                end
            end
            
            %transitions
            eventlist = unique(app.RWA.MultTrans.Evnt);
            app.TransTypeDD.Items = [{''},cellstr(eventlist)]; app.TransTypeDD.Value = '';
          
            %patient
            patientlist = {'1','2','3','4','5'};
            app.PatientTypeDD.Items = [{''},patientlist]; app.PatientTypeDD.Value = '';

            %walk
            walklist = {'1','2','3','4','5','6','7','8'};
            app.WalkTypeDD.Items = [{''},walklist]; app.WalkTypeDD.Value = '';
            
        end

        % Button pushed function: OpenTableButton
        function OpenTableButtonPushed(app, event)
            transtype = app.TransTypeDD.Value;
            patienttype = app.PatientTypeDD.Value;
            walktype = app.WalkTypeDD.Value;
           
            b = [];
            b(1) = isempty(transtype);
            b(2) = isempty(patienttype);
            b(3) = isempty(walktype);

            %Perform some checks
            if any(b)
                uialert(app.UIFigure,'Make sure to select all options!','error');
                return;
            end

            pt = str2double(patienttype);

            app.RWA.filterMultTransData('transtype',transtype,'regiontype','Custom','walktype',walktype,'desctype','','patienttype',patienttype,'customregion',[repmat(pt,4,1),(1:4)']); 
            
            mt = app.RWA.MultTrans.MT(:,{'patient','walk','chan','npidx','evnt','desc','regionlabel'});

            SS = [];
            SS.mt = mt;
            SS.transtype = transtype;
            SS.patienttype = patienttype;
            SS.walktype = walktype;

            fh = uifigure('Position',[50,50,900,400]);
            fh.Name = 'double-click row to plot';
            uitable('Parent',fh,'Data',mt,'DoubleClickedFcn',{@app.evntSelFcn,SS},'Units','normalized','Position',[0.01,0.01,0.98,0.98]);

            % uiwait(fh);
        end

        % Value changed function: PredIdxEdit
        function PredIdxEditValueChanged(app, event)
            if any(ishandle(app.fH))
                SS = getappdata(app.fH,'SS');
                aH3 = SS.aH3;
                pH3 = SS.pH3;
                predlist = SS.predlist;
                predidxs = str2num(app.PredIdxEdit.Value);
                predytick = -(0:size(predlist,2)-1)*SS.predyscale;
                set(pH3,'visible','off')
                if isempty(predidxs)
                    predidxs = 1:size(predlist,2);
                end
                pytick = predytick(1:length(predidxs));
                for k=1:length(predidxs)
                    idx = predidxs(k);
                    ph = pH3(idx);
                    y = get(ph,'YData');
                    y = y-mean(y);
                    y = y+pytick(k);
                    set(ph,'visible','on','ydata',y);
                end
                ylim(aH3,[pytick(end)-SS.predyscale,pytick(1)+SS.predyscale])
                set(aH3,'FontSize',12,'YTick',fliplr(pytick)','YTickLabel',cellfun(@num2str,num2cell(fliplr(pytick)),'UniformOutput',false)')
                plist = fliplr(predlist(2,predidxs));
                for k=1:length(aH3.YTick)
                    aH3.YTickLabel{k} = sprintf('\\color[rgb]{%f,%f,%f}%s', [1,1,1], plist{k});
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TransTypeLabel = uilabel(app.UIFigure);
            app.TransTypeLabel.Text = 'Transition';
            app.TransTypeDD = uidropdown(app.UIFigure);
            app.TransTypeDD.Items = {''};
            app.TransTypeDD.Editable = 'on';
            app.TransTypeDD.Tooltip = {'| symbol can be added between transitions to include more than one'};
            app.TransTypeDD.Value = '';
            app.TransTypeLabel_5 = uilabel(app.UIFigure);
            app.TransTypeLabel_5.Text = 'Walk';
            app.WalkTypeDD = uidropdown(app.UIFigure);
            app.WalkTypeDD.Items = {''};
            app.WalkTypeDD.Editable = 'on';
            app.WalkTypeDD.Tooltip = {'A list of walk numbers can be added manually (i.e. 1,2,7)'};
            app.WalkTypeDD.Value = '';
            app.TransTypeLabel_8 = uilabel(app.UIFigure);
            app.TransTypeLabel_8.Text = 'Patient';
            app.PatientTypeDD = uidropdown(app.UIFigure);
            app.PatientTypeDD.Items = {''};
            app.PatientTypeDD.Editable = 'on';
            app.PatientTypeDD.Tooltip = {'A list of patient numbers can be added manually (i.e. 1,3,4)'};
            app.PatientTypeDD.Value = '';
            app.OpenTableButton = uibutton(app.UIFigure, 'push');
            app.OpenTableButton.ButtonPushedFcn = createCallbackFcn(app, @OpenTableButtonPushed, true);
            app.OpenTableButton.Text = 'Open Table';
            app.SaveDirLabel = uilabel(app.UIFigure);
            app.SaveDirLabel.Text = 'Save Dir';
            app.SelDirEdit = uieditfield(app.UIFigure, 'text');
            app.PredIdxEdit = uieditfield(app.UIFigure, 'text');
            app.PredIdxEdit.ValueChangedFcn = createCallbackFcn(app, @PredIdxEditValueChanged, true);
            app.PredIdxEdit.Tooltip = {'comma separated predictor indices for specgram overlay when "All" is selected from dropdown'};
            app.PredictorList12310Label = uilabel(app.UIFigure);
            app.PredictorList12310Label.Text = 'Predictor List (1,2,3,..10)';
            app.LFP2Vel3Fix4KDE5Amb6Eda7PeT8PeA9PeB10HT11FELabel = uilabel(app.UIFigure);
            app.LFP2Vel3Fix4KDE5Amb6Eda7PeT8PeA9PeB10HT11FELabel.Text = {'1) LFP'; '2) Vel'; '3) Fix'; '4) KDE'; '5) Amb'; '6) Eda'; '7) PeT'; '8) PeA'; '9) PeB'; '10) HT'; '11) FE'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpecGramVideoGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end