classdef RWNApp_EventViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UITable   matlab.ui.control.Table
    end

    
    properties (Access = private)
        RWN; % Description
        CurRow;
        SortCnt=0; % Description
    end
    
    methods (Access = public)
        
        function updateTableFcn(app, varargin)
            app.UITable.Data =  app.RWN.evnts_tbl;
            app.UITable.ColumnName = app.RWN.evnts_tbl.Properties.VariableNames;
        end

    end
    
    methods (Access = private)
        
        function dispSummaryFcn(app, varargin)
            runflag = true;
            if ~isempty(app.UITable.Selection)
                if app.UITable.Selection(1,2)==2
                    runflag = false; %skip if the description column/cell is selected
                end
            end
            if runflag
                [uE,~,uEIdx] = unique(app.UITable.Data.Event);
                uCnt = nan(size(uE));
                uMrk = false(size(uE));
                for k=1:length(uE)
                    idx = (k==uEIdx);
                    uCnt(k) = sum(idx);
                    pf = app.UITable.Data.PupilFrame(idx);
                    if length(unique(pf))~=length(pf)
                        uMrk(k) = true;
                    end
                end
                fH = figure;
                aH = axes('parent',fH);
                plot(aH,uCnt);
                hold(aH,'on');
                plot(aH,find(uMrk),uCnt(uMrk),'*k');
                set(aH,'xtick',1:length(uE),'xticklabel',uE)
                xlim(aH,[0,length(uE)+1])
            end
        end
    
        function sortEventsFcn(app, varargin)
            runflag = true;
            if ~isempty(app.UITable.Selection)
                if app.UITable.Selection(1,2)==2
                    runflag = false; %skip if the description column/cell is selected
                end
            end
            if runflag
                switch app.SortCnt
                    case 0
                        app.RWN.evnts_tbl = sortrows(app.RWN.evnts_tbl,{'Event','NTP'},'ascend');
                    case 1
                        app.RWN.evnts_tbl = sortrows(app.RWN.evnts_tbl,{'Event','NTP'},'descend');
                    case 2
                        app.RWN.evnts_tbl = sortrows(app.RWN.evnts_tbl,{'NTP'},'ascend');
                end
                app.SortCnt = app.SortCnt+1;
                if app.SortCnt>2
                    app.SortCnt = 0;
                end
                app.UITable.Data = app.RWN.evnts_tbl;
            end
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.RWN = varargin{1};
            app.RWN.RWN_EventViewer = app;
            app.UITable.Data =  app.RWN.evnts_tbl;
            app.UITable.ColumnName = app.RWN.evnts_tbl.Properties.VariableNames;
            app.UITable.ColumnEditable = false(1,size(app.UITable.Data,2));
            app.UITable.ColumnEditable(2) = true; %make description column editable
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.CurRow = event.Indices(:,1);
            if app.RWN.GoProCheckBox.Value
                app.RWN.cf = app.RWN.evnts_tbl.GoProFrame(app.CurRow(1));
            else
                app.RWN.cf = app.RWN.evnts_tbl.PupilFrame(app.CurRow(1));
            end
            app.RWN.updateFcn;
        end

        % Key press function: UITable
        function UITableKeyPress(app, event)
            switch event.Key
                case {'backspace','delete'}
                    if event.Source.Selection(1,2)~=2
                        r = uiconfirm(app.UIFigure,"Delete table entry?","");
                        if strcmp(r,'OK')
                            if ~isempty(app.CurRow)
                                app.RWN.evnts_tbl(app.CurRow,:) = [];
                                app.UITable.Data =  app.RWN.evnts_tbl;
                            end
                        end
                    end
                case 's'
                    app.sortEventsFcn;
                case 'd'
                    app.dispSummaryFcn;
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2)==2 %only change description column
                app.RWN.evnts_tbl.Description(indices(1)) = newData;
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            switch event.Key
                case 's'
                    app.sortEventsFcn;
                case 'd'
                    app.dispSummaryFcn;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 's=sort; d=display';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.Tag = 'Press ''s'' to sort the events column. Press ''d'' to show summary stats.';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.KeyPressFcn = createCallbackFcn(app, @UITableKeyPress, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RWNApp_EventViewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end